classdef NBS < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        MainLayout                  matlab.ui.container.GridLayout
        SettingsLayout              matlab.ui.container.GridLayout
        Padding__                   matlab.ui.control.Label
        StatusLabel                 matlab.ui.control.Label
        ControlPanel                matlab.ui.container.Panel
        CtrlLayout                  matlab.ui.container.GridLayout
        NumBodiesField              matlab.ui.control.Spinner
        TrailSlider                 matlab.ui.control.Slider
        TraillengthLabel            matlab.ui.control.Label
        NumberofbodiesLabel         matlab.ui.control.Label
        PresetDropDown              matlab.ui.control.DropDown
        SmoothnessLabel             matlab.ui.control.Label
        TimestepdtLabel             matlab.ui.control.Label
        GravitationalConstantLabel  matlab.ui.control.Label
        GravSlider                  matlab.ui.control.Slider
        TimeStepSlider              matlab.ui.control.Slider
        SoftenSlider                matlab.ui.control.Slider
        PPButton                    matlab.ui.control.StateButton
        ResetButton                 matlab.ui.control.Button
        NBodySimulationLabel        matlab.ui.control.Label
        SimAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        N,
        pos,
        vel,
        mass,
        colors,
        radii
        trailX,
        trailY,
        trailLen
        bodyPlots,
        trailLines
        G,
        dt,
        softening,
        simTime,
        stepCount
        simTimer,
        isRunning = false,
        isInit = false
    end
    
methods (Access = private)
        function initSimulation(app)
            app.stopSim();

            app.N = app.NumBodiesField.Value;
            app.G = app.GravSlider.Value;
            app.dt = app.TimeStepSlider.Value;
            app.softening = app.SoftenSlider.Value;
            app.trailLen = round(app.TrailSlider.Value);
            app.simTime = 0;
            app.stepCount = 0;
            app.setupPreset(app.PresetDropDown.Value);
            app.initGraphics();
            app.isInit = true;
            app.updateStats();
        end

        function setupPreset(app, preset)
            N = app.N;
            G = app.G;
            app.mass = ones(N, 1); 
            switch preset
                case 'Random Cluster'
                    R = 25;
                    app.pos = (rand(N, 2) - 0.5) * 2 * R;
                    vScale = sqrt(G * N / R) * 0.4;
                    app.vel = (rand(N, 2) - 0.5) * vScale;
                case 'Solar System'
                    N = min(N, 12);
                    app.N = N;
                    app.mass = ones(N, 1) * 0.2;
                    app.mass(1) = 150;
                    app.pos = zeros(N, 2);
                    app.vel = zeros(N, 2);
                    for i = 2:N
                        r = 12 + (i-2) * 7;
                        theta = rand() * 2 * pi;
                        app.pos(i,:) = [r*cos(theta), r*sin(theta)];
                        v = sqrt(G * app.mass(1) / r);
                        app.vel(i,:) = [-sin(theta), cos(theta)] * v;
                    end
                case 'Binary System'
                    mStar = 80;
                    sep = 12;
                    app.mass(1:2) = mStar;
                    app.pos(1,:) = [-sep, 0];
                    app.pos(2,:) = [sep, 0];
                    vOrbit = sqrt(G * mStar / (4 * sep));
                    app.vel(1,:) = [0, vOrbit]; app.vel(2,:) = [0, -vOrbit];
                    for i = 3:N
                        r = 35 + rand()*15;
                        th = rand() * 2 * pi;
                        app.pos(i,:) = [r*cos(th), r*sin(th)];
                        app.vel(i,:) = [-sin(th), cos(th)] * sqrt(G * 2 * mStar / r);
                    end
            end
            comV = sum(app.mass .* app.vel) / sum(app.mass);
            app.vel = app.vel - comV;
            app.colors = hsv(app.N);
            app.radii = (5 + 12 * (app.mass / max(app.mass)).^(1/3)).^2;
            app.trailX = cell(N,1); app.trailY = cell(N,1);
            for i=1:N
                app.trailX{i} = app.pos(i, 1);
                app.trailY{i} = app.pos(i, 2);
            end
        end

        function initGraphics(app)
            cla(app.SimAxes);
            hold(app.SimAxes, 'on');
            sPos = (rand(200, 2) - 0.5) * 1200;
            scatter(app.SimAxes, sPos(:,1), sPos(:,2), 1.5, [0.3 0.3 0.5], 'filled', 'HandleVisibility', 'off');
            app.trailLines = gobjects(app.N,1);
            for i = 1:app.N
                app.trailLines(i) = line(app.SimAxes, app.pos(i,1), app.pos(i,2), 'Color', [app.colors(i,:), 0.4], 'LineWidth', 1.2);
            end
            app.bodyPlots = scatter(app.SimAxes, app.pos(:,1), app.pos(:,2), app.radii, app.colors, 'filled', 'MarkerEdgeColor', [1 1 1], 'LineWidth', 0.5);
            hold(app.SimAxes, 'off');
        end

        function stepSimulation(app)
            m = app.mass;
            G = app.G;
            dt = app.dt;
            e2 = app.softening^2;
            a1 = nbodyAcc(app, app.pos, m, G, e2);
            vMid = app.vel + a1 * (dt/2);
            app.pos = app.pos + vMid * dt;
            a2 = nbodyAcc(app, app.pos, m, G, e2);
            app.vel = vMid + a2 * (dt/2);
            app.simTime = app.simTime + dt;
            app.stepCount = app.stepCount + 1;
        end

        function updateGraphics(app)
            if ~isvalid(app.UIFigure)
                return;
            end
            for i = 1:app.N
                app.trailX{i}(end + 1) = app.pos(i, 1);
                app.trailY{i}(end + 1) = app.pos(i, 2);
                if numel(app.trailX{i}) > app.trailLen
                    app.trailX{i}(1) = [];
                    app.trailY{i}(1) = [];
                end
                set(app.trailLines(i), 'XData', app.trailX{i}, 'YData', app.trailY{i});
            end
            set(app.bodyPlots, 'XData', app.pos(:,1), 'YData', app.pos(:,2));
            limit = max(max(abs(app.pos))) * 1.3 + 10;
            axis(app.SimAxes, [-limit limit -limit limit]);
            drawnow limitrate;
        end

        function updateStats(app)
            %ke = 0.5 * sum(app.mass .* sum(app.vel.^2, 2));
            %app.KineticValue.Text = sprintf('KE: %.2f', ke);
            %app.SimTimeValue.Text = sprintf('Time: %.2f', app.simTime);
            %app.StepValue.Text = sprintf('Steps: %d', app.stepCount);
        end

        function timerFcn(app, ~, ~)
            if ~app.isRunning || ~isvalid(app.UIFigure), return; end
            try
                for k = 1:4
                    app.stepSimulation();
                end
                app.updateGraphics();
                if mod(app.stepCount, 40) == 0, app.updateStats(); end
            catch
                app.stopSim();
            end
        end
        
        function startSim(app)
            if ~app.isInit
                app.initSimulation();
            end

            app.isRunning = true;
            % The fix: only start if it's currently stopped
            if isvalid(app.simTimer) && strcmp(app.simTimer.Running, 'off')
                start(app.simTimer);
            end
        end

        function stopSim(app)
            app.isRunning = false;
            if isvalid(app.simTimer) && strcmp(app.simTimer.Running, 'on')
                stop(app.simTimer);
            end
        end        
        
        function acc = nbodyAcc(~, pos, m, G, e2)
             N = size(pos,1);
             dx = pos(:,1)' - pos(:,1); 
             dy = pos(:,2)' - pos(:,2);
             r2 = dx.^2 + dy.^2 + e2;
             invR3 = r2.^(-1.5);
             invR3(1:N + 1:end) = 0; 
             fac = G * (m') .* invR3;
             acc = [sum(fac.*dx, 2), sum(fac.*dy, 2)];
         end
        
        function rgbColor = colorToNormalizedRGB(~, color)
            rgbColor = color ./ 255;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.simTimer = timer('ExecutionMode','fixedRate','Period',0.033,'TimerFcn',@app.timerFcn, 'BusyMode', 'drop');
            app.initSimulation();
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.stopSim();
            if isvalid(app.simTimer)
                delete(app.simTimer);
            end
            delete(app);
        end

        % Callback function: GravSlider, NumBodiesField, PresetDropDown, 
        % ...and 4 other components
        function OnResetPressed(app, event)
            if app.PPButton.Value == 1
                app.PPButton.Value = 0;
                OnPPPressed(app, event);
            end
            app.initSimulation();
        end

        % Value changed function: PPButton
        function OnPPPressed(app, event)
            value = app.PPButton.Value;
            if value == 1 % VALUE 1 means sim is stopped, start the simulation
                app.startSim();
                app.PPButton.Text = "PAUSE";
                app.PPButton.BackgroundColor = colorToNormalizedRGB(app, [153, 153, 153]);
                app.ControlPanel.Visible = "off";
                app.StatusLabel.Text = "SIMULATION IS RUNNING";
            else % VALUE 0 means sim is currently running, stop the simulation
                app.stopSim();
                app.PPButton.Text = "PLAY";
                app.PPButton.BackgroundColor = colorToNormalizedRGB(app, [153, 255, 51]);
                app.ControlPanel.Visible = "on";
                app.StatusLabel.Text = "";
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.MainLayout = uigridlayout(app.UIFigure);
            app.SimAxes = uiaxes(app.MainLayout);
            app.SimAxes.XTick = [];
            app.SimAxes.YTick = [];
            app.SimAxes.ZTick = [];
            app.SettingsLayout = uigridlayout(app.MainLayout);
            app.NBodySimulationLabel = uilabel(app.SettingsLayout);
            app.NBodySimulationLabel.Text = 'NBodySimulation';
            app.ResetButton = uibutton(app.SettingsLayout, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.ResetButton.Text = 'STOP';
            app.PPButton = uibutton(app.SettingsLayout, 'state');
            app.PPButton.ValueChangedFcn = createCallbackFcn(app, @OnPPPressed, true);
            app.PPButton.Text = 'PLAY';
            app.ControlPanel = uipanel(app.SettingsLayout);
            app.CtrlLayout = uigridlayout(app.ControlPanel);
            app.SoftenSlider = uislider(app.CtrlLayout);
            app.SoftenSlider.Limits = [0.1 5];
            app.SoftenSlider.MajorTicks = [0.1 0.5 0.8 1.33 1.74 5];
            app.SoftenSlider.MajorTickLabels = {'0.1', '0.5', '0.8', '1.33', '1.74', '5', ''};
            app.SoftenSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.SoftenSlider.MinorTicks = [];
            app.SoftenSlider.Value = 0.8;
            app.TimeStepSlider = uislider(app.CtrlLayout);
            app.TimeStepSlider.Limits = [0.005 0.1];
            app.TimeStepSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.TimeStepSlider.MinorTicks = [];
            app.TimeStepSlider.Value = 0.02;
            app.GravSlider = uislider(app.CtrlLayout);
            app.GravSlider.Limits = [0.5 5];
            app.GravSlider.MajorTicks = [0.5 1 2 3 4 5];
            app.GravSlider.MajorTickLabels = {'0.5', '1', '2', '3', '4', '5'};
            app.GravSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.GravSlider.MinorTicks = [];
            app.GravSlider.Value = 1;
            app.GravitationalConstantLabel = uilabel(app.CtrlLayout);
            app.GravitationalConstantLabel.Text = 'Gravitational Constant';
            app.TimestepdtLabel = uilabel(app.CtrlLayout);
            app.TimestepdtLabel.Text = 'Time step (dt)';
            app.SmoothnessLabel = uilabel(app.CtrlLayout);
            app.SmoothnessLabel.Text = 'Smoothness';
            app.PresetDropDown = uidropdown(app.CtrlLayout);
            app.PresetDropDown.Items = {'Random Cluster', 'Solar System', 'Binary System'};
            app.PresetDropDown.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.PresetDropDown.Value = 'Random Cluster';
            app.NumberofbodiesLabel = uilabel(app.CtrlLayout);
            app.NumberofbodiesLabel.Text = 'Number of bodies';
            app.TraillengthLabel = uilabel(app.CtrlLayout);
            app.TraillengthLabel.Text = 'Trail length';
            app.TrailSlider = uislider(app.CtrlLayout);
            app.TrailSlider.Limits = [5 500];
            app.TrailSlider.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.TrailSlider.Value = 150;
            app.NumBodiesField = uispinner(app.CtrlLayout);
            app.NumBodiesField.Limits = [2 15];
            app.NumBodiesField.ValueDisplayFormat = '%.0f';
            app.NumBodiesField.ValueChangedFcn = createCallbackFcn(app, @OnResetPressed, true);
            app.NumBodiesField.Tooltip = {'Number of celestial bodies in the simulation'; 'Minimum: 2'; 'Maximum: 15'};
            app.NumBodiesField.Value = 8;
            app.StatusLabel = uilabel(app.SettingsLayout);
            app.StatusLabel.Text = '';
            app.Padding__ = uilabel(app.SettingsLayout);
            app.Padding__.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NBS

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end